classdef application3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TextArea                matlab.ui.control.TextArea
        GCodeButton             matlab.ui.control.Button
        ClickHereFirstLabel     matlab.ui.control.Label
        MiseajourButton         matlab.ui.control.Button
        DropDown_2              matlab.ui.control.DropDown
        DropDown                matlab.ui.control.DropDown
        CiseilleEditField       matlab.ui.control.NumericEditField
        CiseilleEditFieldLabel  matlab.ui.control.Label
        Outil2EditField         matlab.ui.control.NumericEditField
        Outil2EditFieldLabel    matlab.ui.control.Label
        Outil1EditField         matlab.ui.control.NumericEditField
        Outil1EditFieldLabel    matlab.ui.control.Label
        ContentTextArea         matlab.ui.control.TextArea
        ContentTextAreaLabel    matlab.ui.control.Label
        PathEditField           matlab.ui.control.EditField
        PathEditFieldLabel      matlab.ui.control.Label
        OuvrirButton            matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Distance_Matrix % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OuvrirButton
        function OuvrirButtonPushed(app, event)
            [Filename,Path] = uigetfile('*.dxf');
            if isequal(Filename,0)
                app.ContentTextArea.Value = 'You Clicked Cancel';
                app.PathEditField.Value = 'No Path Found';
                return;
            end


            % Prendre les valeurs numerique depuis les cases
             d1_value = app.Outil1EditField.Value;
             d2_value = app.Outil2EditField.Value;
             d3_value = app.CiseilleEditField.Value;
    
            % Ajouter les valeur a notre matrice
             app.Distance_Matrix = [app.Distance_Matrix; d1_value, d2_value, d3_value];


fid=fopen(fullfile(Path, Filename),'r');%Ouvrir un fichier en mode de lecture
global m1;
global m;
global combinedMatrix;

m1 = []; % Matrice pour les cercles
m = []; %  Matrice pour les lignes
NCercle = 0;
NLigne = 0;

% Boucle pour parcourir le fichier
while ~feof(fid)
    tline = fgetl(fid);
    % AcDbCircle ==> Cercle
    if strcmp(tline, 'AcDbCircle')
        NCercle = NCercle + 1;
        % read x, y, z, and R values for circle
        fgetl(fid); %10
        x=fgetl(fid);
        fgetl(fid);%20
        y=fgetl(fid);
        fgetl(fid);%30
        %z=fgetl(fid);
        fgetl(fid);%z
        fgetl(fid);%40
        R=fgetl(fid);%r

        % Convertir les valeurs en valeur num et les stocker dans la
        % matrice m1
        m1(end+1,:) = [sscanf(x,'%f'), sscanf(y,'%f'), sscanf(R,'%f')];
    % AcDbLine ==>Ligne
    elseif strcmp(tline, 'AcDbLine')
        NLigne = NLigne + 1;
        % read x1, y1, z1, x2, y2, and z2 values for line
        fgetl(fid);
        x1=fgetl(fid);
        fgetl(fid);
        y1=fgetl(fid);
        fgetl(fid);
        fgetl(fid);
        %z1=fgetl(fid);
        fgetl(fid);
        x2=fgetl(fid);
        fgetl(fid);
        y2=fgetl(fid);
        fgetl(fid);
        %z2=fgetl(fid);

         % Convertir les valeurs en valeur num et les stocker dans la
         % matrice m
        m(end+1,:) = [sscanf(x1,'%f'), sscanf(y1,'%f'),sscanf(x2,'%f'), sscanf(y2,'%f')];
    end
end


A = m(1,1);
B = m(1,2);

%La taille de notre piece le X
Taille_Piece = m(1,3) - A;

%Translation des cercles pour obtenir les emplacements reelles
m1_Copie = m1;
m1_Copie(:,1) = m1_Copie(:,1) - A;
m1_Copie(:,2) = m1_Copie(:,2) - B;

% Obtenir la distance qui se trouve entre le centre de chaque cercle et l'extremite de droite
m1_Copie1 = m1;
Outil =  Taille_Piece - m1_Copie(:,1);
m1_Copie1(:,1) = Outil;


fclose(fid);
% l'utilistaion de fopen pour creer un fichier .txt ,ou on va mettre les
% valeurs de C(x,y,r)  L(x1,y1;x2,y2)
fid2=fopen('points_out.txt','w');
fprintf(fid2,'Les coordonnnees des cercles(%d) : \n\n\n',NCercle);
fprintf(fid2,'%d\t\t%d\t\t%d\n',m1');
fprintf(fid2,'\n');
fprintf(fid2,'Les coordonnnees des lignes(%d) : \n\n\n',NLigne);
fprintf(fid2,'%d\t\t%d\t\t%d\t\t%d\n',m');
fprintf(fid2,'\n');
fprintf(fid2,'Les distances : \n\n\n');
fprintf(fid2,'Outil1 : ');
fprintf(fid2,'%d\t\t%d\t\t%d \n',d1_value);
fprintf(fid2,'Outil2 : ');
fprintf(fid2,'%d\t\t%d\t\t%d \n',d2_value);
fprintf(fid2,'Ciseille : ');
fprintf(fid2,'%d\t\t%d\t\t%d \n',d3_value);
fprintf(fid2,'\n\n');
fprintf(fid2,'La translation des cercles : \n\n\n');
fprintf(fid2,'%d\t\t%d\t\t%d\n',m1_Copie');
fprintf(fid2,'\n\n');
fprintf(fid2,'Les distance entre chaque centre de cercle et l_extremite doite  : \n\n\n');
fprintf(fid2,'%d\n',Outil);
fprintf(fid2,'\n\n');
fprintf(fid2,'Les outils  : \n\n\n');

%Les tailles de nos cercles
R1 = str2double(app.DropDown.Value);
R2 = str2double(app.DropDown_2.Value);

% Initialisation d'une cell pour avoir la possibilite de mixer les valeurs
% numeriques et les chaines de characteres 
outils = cell(size(m1_Copie1, 1), 1);

% Boucle sur chaque ligne de la matrice
for i = 1:size(m1_Copie1, 1)
    % Vérifier si la valeur de la troisième colonne est égale à R1
    if m1_Copie1(i, 3) == R1
        % Ajouter la distance de la première outil1
        m1_Copie1(i, 1) = m1_Copie1(i, 1) + d1_value;
        % Enregistrer la chaîne de caractères 'outil1'
        outils{i} = 'outil1';
    elseif m1_Copie1(i, 3) == R2
        % Ajouter la distance de la deuxième outil2
        m1_Copie1(i, 1) = m1_Copie1(i, 1) + d2_value;
        % Enregistrer la chaîne de caractères 'outil2'
        outils{i} = 'outil2';
    end
end


% Ordonner m1_Copie1 en ordre croissant selon la première colonne
m1_Copie1 = sortrows(m1_Copie1, 1);


% Boucle sur chaque ligne de la matrice
for i = 1:size(m1_Copie1, 1)
    % Écrivez la valeur dans la première colonne et la chaîne correspondante
    fprintf(fid2, '%d %s\n', m1_Copie1(i, 1), outils{i});
end
fprintf(fid2,'%d %s\n',Taille_Piece+d3_value,'Ciseille');


fclose(fid2);


% Le mappage de (outil1 -- 1 , outil2 -- 2)
outil_mapping = containers.Map({'outil1', 'outil2'}, {1, 2});

% Conversion d’outils en valeurs numériques à l’aide du mappage
outils_numeric = cellfun(@(x) outil_mapping(x), outils);

% Concaténer horizontalement m1copie et outils_numeric 
combinedMatrix = horzcat(m1_Copie(:, 1), outils_numeric);

% Ajouter une ligne à la fin de combinedMatrix (La ligne [La distance de la ciseille , 3])
new_row = [Taille_Piece + d3_value, 3];
combinedMatrix = vertcat(combinedMatrix, new_row);
 
%Utilisation de l’étendue globale pour utiliser sa valeur en dehors de cette fonction
global combinedMatrix


temp = fileread('points_out.txt');
            app.ContentTextArea.Value = temp;
            app.PathEditField.Value = fullfile(Path, Filename);
            figure(app.UIFigure);

             
             app.Distance_Matrix = [];
        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(app, event)
            value = app.PathEditField.Value;
            
        end

        % Value changed function: ContentTextArea
        function ContentTextAreaValueChanged(app, event)
            value = app.ContentTextArea.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
        global m1;
        global m;

            %S'il y a deux matrices vides m et m1, nous allons sortir de la fonction
            if isempty(m) && isempty(m1)
            return;
            end
            
    %Definir le X et Le Y de coin inférieur gauche
    a = m(1,1); %X
    b = m(1,2); %Y
    % Creation de la vecteur des angles en radian
    theta = linspace(0, 2*pi, 1000);

    % Tracez chaque cercle dans la matrice sur les Axes
    for i = 1:size(m1, 1) %size(m1, 1) : Le nombre des lignes dans la matrice m1 , (X,1): N lignes ,(X,2):N Colomnes.
        x = m1(i, 1) - a + m1(i, 3)*cos(theta);
        y = m1(i, 2) - b + m1(i, 3)*sin(theta);
        plot(app.UIAxes, x, y);  
        hold(app.UIAxes, 'on');  % Pour rester dans la meme figure
    end
    %Tracer les lignes
    x = [m(:, 1)' m(:, 3)'] - a;
    y = [m(:, 2)' m(:, 4)'] - b;
    plot(app.UIAxes, x, y);

    hold(app.UIAxes, 'off'); 
    % Nettoyage des deux matrices pour contenir de nouvelles données
    m = [];
    m1 = [];
    % Definir les limites de chaque axes (X,Y)
    xlim(app.UIAxes, [0, 3500]);
    ylim(app.UIAxes, [0, 1000]);
        end

        % Value changed function: Outil1EditField
        function Outil1EditFieldValueChanged(app, event)
            value = app.Outil1EditField.Value;
            
        end

        % Value changed function: Outil2EditField
        function Outil2EditFieldValueChanged(app, event)
            value = app.Outil2EditField.Value;
            
        end

        % Value changed function: CiseilleEditField
        function CiseilleEditFieldValueChanged(app, event)
            value = app.CiseilleEditField.Value;
            
        end

        % Button pushed function: MiseajourButton
        function MiseajourButtonPushed(app, event)
            [Filename_NO_USE,Path_NO_USE] = uigetfile('*.dxf');
            fid=fopen(fullfile(Path_NO_USE, Filename_NO_USE),'r');%Ouvrir un fichier en mode de lecture

            m1 = []; % Matrice pour les cercles
            m = []; %  Matrice pour les lignes
            NCercle = 0;
            NLigne = 0;
            
            % Boucle pour parcourir le fichier
            while ~feof(fid)
                tline = fgetl(fid);
                % AcDbCircle ==> Cercle
                if strcmp(tline, 'AcDbCircle')
                    NCercle = NCercle + 1;
                    % read x, y, z, and R values for circle
                    fgetl(fid); %10
                    x=fgetl(fid);
                    fgetl(fid);%20
                    y=fgetl(fid);
                    fgetl(fid);%30
                    %z=fgetl(fid);
                    fgetl(fid);%z
                    fgetl(fid);%40
                    R=fgetl(fid);%r
            
                    % Convertir les valeurs en valeur num et les stocker dans la
                    % matrice m1
                    m1(end+1,:) = [sscanf(x,'%f'), sscanf(y,'%f'), sscanf(R,'%f')];
                % AcDbLine ==>Ligne
                elseif strcmp(tline, 'AcDbLine')
                    NLigne = NLigne + 1;
                    % read x1, y1, z1, x2, y2, and z2 values for line
                    fgetl(fid);
                    x1=fgetl(fid);
                    fgetl(fid);
                    y1=fgetl(fid);
                    fgetl(fid);
                    fgetl(fid);
                    %z1=fgetl(fid);
                    fgetl(fid);
                    x2=fgetl(fid);
                    fgetl(fid);
                    y2=fgetl(fid);
                    fgetl(fid);
                    %z2=fgetl(fid);
            
                     % Convertir les valeurs en valeur num et les stocker dans la
                     % matrice m
                    m(end+1,:) = [sscanf(x1,'%f'), sscanf(y1,'%f'),sscanf(x2,'%f'), sscanf(y2,'%f')];
                end
            end


            % Extraire les valeurs de la troisième colonne
            values = unique(m1(:, 3));

            
            % Mettre à jour le menu déroulant avec les valeurs extraites
            app.DropDown.Items = string(values);
            app.DropDown_2.Items = string(values);

            %Mise a jour des valeurs des entrees (Outil1,Outil2,Ciseille)
                app.Outil1EditField.Value = 100;
                app.Outil2EditField.Value = 200;
                app.CiseilleEditField.Value = 1000;
            uialert(app.UIFigure, 'Les valeurs ont été importées avec succès', 'Success');


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            
        end

        % Button pushed function: GCodeButton
        function GCodeButtonPushed(app, event)
global combinedMatrix;
% Exemple de matrice avec distance et valeurs d’outil
disp(combinedMatrix);

% Les deux valeurs de raoyns qu'on a
R1 = str2double(app.DropDown.Value);
R2 = str2double(app.DropDown_2.Value);

% Initialisation de positions
currentX = 0;
currentY = 0;

% Ouvrir le fichier texte pour l’écriture
fid = fopen('generated_gcode.txt', 'w');

% Utilisez le mouvement absolu et les millimètres
fprintf(fid, 'G90 ; Use absolute motion\n');
fprintf(fid, 'G21 ; Use millimeters\n');

% Itérer dans la matrice et générer des commandes G-code
for i = 1:size(combinedMatrix, 1)
    distance = combinedMatrix(i, 1); 
    tool = combinedMatrix(i, 2); 

    % Calculer la position absolue vers laquelle se déplacer dans X et Y
    absX = distance;
    absY = 250;

    % Générer une commande G-code basée sur l’outil
    if tool == 1
    fprintf(fid, 'G0 X%.1f Y%.1f ; Se déplacer à la position de départ\n', absX, absY);
    fprintf(fid, 'G1 Z5 F100 ; Déplacer l outil a la profondeur de coupe\n');
    fprintf(fid, 'G2 X0 Y250 R%.1f ; Dessiner un cercle avec un rayon de %.1f en utilisant outil1\n', R1, R1);
    fprintf(fid, 'G1 Z10 ; Déplacer l outil au-dessus de la surface de la piece\n');
elseif tool == 2
    fprintf(fid, 'G0 X%.1f Y%.1f ; Se déplacer à la position de départ\n', absX, absY);
    fprintf(fid, 'G1 Z5 F100 ; Déplacer l outil a la profondeur de coupe\n');
    fprintf(fid, 'G2 X0 Y250 R%.1f ; Dessiner un cercle avec un rayon de %.1f en utilisant outil2\n', R2, R2);
    fprintf(fid, 'G1 Z10 ; Déplacer l outil au-dessus de la surface de la piece\n');
elseif tool == 3
    % Générer une commande G-code pour Ciseille
    fprintf(fid, 'G0 X%.1f Y%.1f ; Se déplacer à la position de départ\n', absX, absY);
    fprintf(fid, 'G1 Z5 F100 ; Déplacer l outil a la profondeur de coupe\n');
    fprintf(fid, 'G1 X100 Y100 ; Faire une découpe avec une ciseille\n');
    fprintf(fid, 'G1 Z10 ; Déplacer l outil au-dessus de la surface de la piece\n');
else
    % Gérer les outils non pris en charge ou les erreurs
    disp(['Erreur: Outil non pris en charge - ' num2str(tool)]);
end


    % Mise a jour du position
    currentX = absX;
    currentY = absY;
end

% Close text file
fclose(fid);

% Affichage le message
disp('Generated G-code commands written to generated_gcode.txt file.');


%Affichage du G-Code

    % Overture de fichier
    fileID = fopen('generated_gcode.txt','r');
    fileContents = fscanf(fileID,'%c');
    fclose(fileID);
    
    % Afficher le contenu dans la zone de texte
    app.TextArea.Value = fileContents;

        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'La piece')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.OuvrirButton = uibutton(app.UIFigure, 'push');
            app.OuvrirButton.ButtonPushedFcn = createCallbackFcn(app, @OuvrirButtonPushed, true);
            app.OuvrirButton.Text = 'Ouvrir';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldValueChanged, true);
            app.ContentTextAreaLabel = uilabel(app.UIFigure);
            app.ContentTextAreaLabel.Text = 'Content';
            app.ContentTextArea = uitextarea(app.UIFigure);
            app.ContentTextArea.ValueChangedFcn = createCallbackFcn(app, @ContentTextAreaValueChanged, true);
            app.Outil1EditFieldLabel = uilabel(app.UIFigure);
            app.Outil1EditFieldLabel.Text = 'Outil 1';
            app.Outil1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Outil1EditField.ValueChangedFcn = createCallbackFcn(app, @Outil1EditFieldValueChanged, true);
            app.Outil2EditFieldLabel = uilabel(app.UIFigure);
            app.Outil2EditFieldLabel.Text = 'Outil 2';
            app.Outil2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Outil2EditField.ValueChangedFcn = createCallbackFcn(app, @Outil2EditFieldValueChanged, true);
            app.CiseilleEditFieldLabel = uilabel(app.UIFigure);
            app.CiseilleEditFieldLabel.Text = 'Ciseille ';
            app.CiseilleEditField = uieditfield(app.UIFigure, 'numeric');
            app.CiseilleEditField.ValueChangedFcn = createCallbackFcn(app, @CiseilleEditFieldValueChanged, true);
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.MiseajourButton = uibutton(app.UIFigure, 'push');
            app.MiseajourButton.ButtonPushedFcn = createCallbackFcn(app, @MiseajourButtonPushed, true);
            app.MiseajourButton.Text = 'Mise a jour';
            app.ClickHereFirstLabel = uilabel(app.UIFigure);
            app.ClickHereFirstLabel.Text = '-- Click Here First --';
            app.GCodeButton = uibutton(app.UIFigure, 'push');
            app.GCodeButton.ButtonPushedFcn = createCallbackFcn(app, @GCodeButtonPushed, true);
            app.GCodeButton.Text = 'G-Code';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end